classdef improved_sar_sim_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FMCWMIMOSARSimulatorLabel       matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        RadarSetupTab                   matlab.ui.container.Tab
        FMCWChirpParametersPanel        matlab.ui.container.Panel
        StartingFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        StartingFrequencyGHzEditField   matlab.ui.control.NumericEditField
        FreqSlopeMHzusEditFieldLabel    matlab.ui.control.Label
        FreqSlopeMHzusEditField         matlab.ui.control.NumericEditField
        IdleTimeusEditFieldLabel        matlab.ui.control.Label
        IdleTimeusEditField             matlab.ui.control.NumericEditField
        TXStartTimeusEditFieldLabel     matlab.ui.control.Label
        TXStartTimeusEditField          matlab.ui.control.NumericEditField
        ADCStartTimeusEditFieldLabel    matlab.ui.control.Label
        ADCStartTimeusEditField         matlab.ui.control.NumericEditField
        ADCSamplesEditFieldLabel        matlab.ui.control.Label
        ADCSamplesEditField             matlab.ui.control.NumericEditField
        SamplingFrequencykspsEditFieldLabel  matlab.ui.control.Label
        SamplingFrequencykspsEditField  matlab.ui.control.NumericEditField
        RampEndTimeusEditFieldLabel     matlab.ui.control.Label
        RampEndTimeusEditField          matlab.ui.control.NumericEditField
        CenterFrequencyGHzEditFieldLabel  matlab.ui.control.Label
        CenterFrequencyGHzEditField     matlab.ui.control.NumericEditField
        MaximumBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        MaximumBandwidthGHzEditField    matlab.ui.control.NumericEditField
        TotalBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        TotalBandwidthGHzEditField      matlab.ui.control.NumericEditField
        WavelengthmEditFieldLabel       matlab.ui.control.Label
        WavelengthmEditField            matlab.ui.control.NumericEditField
        MaximumRangemEditFieldLabel     matlab.ui.control.Label
        MaximumRangemEditField          matlab.ui.control.NumericEditField
        RangeResolutionmEditFieldLabel  matlab.ui.control.Label
        RangeResolutionmEditField       matlab.ui.control.NumericEditField
        UsefulBandwidthGHzEditFieldLabel  matlab.ui.control.Label
        UsefulBandwidthGHzEditField     matlab.ui.control.NumericEditField
        TransmitterElementsPanel        matlab.ui.container.Panel
        TxTable                         matlab.ui.control.Table
        AddTxButton                     matlab.ui.control.Button
        RemoveTxButton                  matlab.ui.control.Button
        TransmitterZmEditFieldLabel     matlab.ui.control.Label
        TransmitterZmEditField          matlab.ui.control.NumericEditField
        ReceiverElementsPanel           matlab.ui.container.Panel
        RxTable                         matlab.ui.control.Table
        AddRxButton                     matlab.ui.control.Button
        RemoveRxButton                  matlab.ui.control.Button
        ReceiverZmEditFieldLabel        matlab.ui.control.Label
        ReceiverZmEditField             matlab.ui.control.NumericEditField
        SARSetupPanel                   matlab.ui.container.Panel
        MethodDropDownLabel             matlab.ui.control.Label
        SARMethodDropDown               matlab.ui.control.DropDown
        XStepSizeEditFieldLabel         matlab.ui.control.Label
        XStepSizeEditField              matlab.ui.control.NumericEditField
        NumYStepsEditFieldLabel         matlab.ui.control.Label
        NumYStepsEditField              matlab.ui.control.NumericEditField
        NumXStepsEditFieldLabel         matlab.ui.control.Label
        NumXStepsEditField              matlab.ui.control.NumericEditField
        YStepSizeEditFieldLabel         matlab.ui.control.Label
        YStepSizeEditField              matlab.ui.control.NumericEditField
        NumStepsEditFieldLabel          matlab.ui.control.Label
        NumThetaStepsEditField          matlab.ui.control.NumericEditField
        SizedegEditFieldLabel           matlab.ui.control.Label
        ThetaSizedegEditField           matlab.ui.control.NumericEditField
        XStepSwitch                     matlab.ui.control.Switch
        YStepSwitch                     matlab.ui.control.Switch
        YSizemEditFieldLabel            matlab.ui.control.Label
        YSizemEditField                 matlab.ui.control.NumericEditField
        XSizemEditFieldLabel            matlab.ui.control.Label
        XSizemEditField                 matlab.ui.control.NumericEditField
        SaveLoadFMCWChripParametersPanel  matlab.ui.container.Panel
        FMCWSaveButton                  matlab.ui.control.Button
        FMCWLoadButton                  matlab.ui.control.Button
        FMCWSaveNameEditField           matlab.ui.control.EditField
        FMCWLoadNameEditField           matlab.ui.control.EditField
        SaveLoadMIMOArrayPanel          matlab.ui.container.Panel
        ArraySaveButton                 matlab.ui.control.Button
        ArrayLoadButton                 matlab.ui.control.Button
        ArraySaveNameEditField          matlab.ui.control.EditField
        ArrayLoadNameEditField          matlab.ui.control.EditField
        AntAxes                         matlab.ui.control.UIAxes
        AntVirtualAxes                  matlab.ui.control.UIAxes
        SARAxes                         matlab.ui.control.UIAxes
        SARVirtualAxes                  matlab.ui.control.UIAxes
        TargetSetupTab                  matlab.ui.container.Tab
        SetupTargetButton               matlab.ui.control.Button
        ReconstructImageTab             matlab.ui.container.Tab
        CreatedbyJosiahWSmithLabel      matlab.ui.control.Label
    end

    
    properties (Access = public)
        Params
        fmcw
        ant
        sar
        target
        fig
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath(genpath("../"));
            
            % Create the Tx Table
            app.TxTable.Data = table;
            app.TxTable.ColumnEditable = true;
            
            % Create the Rx Table
            app.RxTable.Data = table;
            app.RxTable.ColumnEditable = true;
            
            % Initialize figures
            app.fig = initializeFigures();
            
            % Update the FMCW chirp parameters
            app.fmcw = updatefmcw(app);
            
            % Get the AWR1243 default antenna array
            ArrayLoadButtonPushed(app, 1);
            app.ant = updateant(app);
            
            % Update the SAR scenario
            app.sar = updatesar(app);
        end

        % Value changed function: StartingFrequencyGHzEditField
        function StartingFrequencyGHzEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: FreqSlopeMHzusEditField
        function FreqSlopeMHzusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: IdleTimeusEditField
        function IdleTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: TXStartTimeusEditField
        function TXStartTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: ADCStartTimeusEditField
        function ADCStartTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: ADCSamplesEditField
        function ADCSamplesEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: SamplingFrequencykspsEditField
        function SamplingFrequencykspsEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: RampEndTimeusEditField
        function RampEndTimeusEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: CenterFrequencyGHzEditField
        function CenterFrequencyGHzEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Value changed function: MaximumBandwidthGHzEditField
        function MaximumBandwidthGHzEditFieldValueChanged(app, event)
            app.fmcw = updatefmcw(app);
        end

        % Button pushed function: AddTxButton
        function AddTxButtonPushed(app, event)
            app.TxTable.Data(end+1,:) = {0,0,0,0,false};
        end

        % Button pushed function: RemoveTxButton
        function RemoveTxButtonPushed(app, event)
            app.TxTable.Data = app.TxTable.Data(1:end-1,:);
        end

        % Button pushed function: AddRxButton
        function AddRxButtonPushed(app, event)
            app.RxTable.Data(end+1,:) = {0,0,0,0,false};
        end

        % Button pushed function: RemoveRxButton
        function RemoveRxButtonPushed(app, event)
            app.RxTable.Data = app.RxTable.Data(1:end-1,:);
        end

        % Button pushed function: FMCWSaveButton
        function FMCWSaveButtonPushed(app, event)
            if exist(app.FMCWSaveNameEditField.Value + ".mat",'file')
                selection = uiconfirm(app.UIFigure,'Are you sure you want to overwrite?','Confirm Overwrite',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Parameters not saved!");
                    return;
                end
            end
            
            savedfmcw = app.fmcw;
            savePathFull = "./saved/" + app.FMCWSaveNameEditField.Value + ".mat";
            save(savePathFull,"savedfmcw");
        end

        % Button pushed function: FMCWLoadButton
        function FMCWLoadButtonPushed(app, event)
            if ~exist(app.FMCWLoadNameEditField.Value + ".mat",'file')
                uiconfirm(app.UIFigure,"No file called " + app.FMCWLoadNameEditField.Value + ".mat to load",'Cannot Load',...
                    "Options",{'OK'},'Icon','warning');
                warning("Parameters not loaded!");
                return;
            end
            
            loadPathFull = "./saved/" + app.FMCWLoadNameEditField.Value + ".mat";
            load(loadPathFull,"savedfmcw");
            
            app.StartingFrequencyGHzEditField.Value = savedfmcw.f0*1e-9;
            app.FreqSlopeMHzusEditField.Value = savedfmcw.K*1e-12;
            app.IdleTimeusEditField.Value = savedfmcw.IdleTime_s*1e6;
            app.TXStartTimeusEditField.Value = savedfmcw.TXStartTime_s*1e6;
            app.ADCStartTimeusEditField.Value = savedfmcw.ADCStartTime_s*1e6;
            app.ADCSamplesEditField.Value = savedfmcw.ADCSamples;
            app.SamplingFrequencykspsEditField.Value = savedfmcw.fS*1e-3;
            app.RampEndTimeusEditField.Value = savedfmcw.RampEndTime_s*1e6;
            app.CenterFrequencyGHzEditField.Value = savedfmcw.fC*1e-9;
            app.MaximumBandwidthGHzEditField.Value = savedfmcw.B_max*1e-9;
            
            app.fmcw = updatefmcw(app);
        end

        % Cell edit callback: TxTable
        function TxTableCellEdit(app, event)
            app.ant = updateant(app);
        end

        % Cell edit callback: RxTable
        function RxTableCellEdit(app, event)
            app.ant = updateant(app);
        end

        % Button pushed function: ArraySaveButton
        function ArraySaveButtonPushed(app, event)
            if exist(app.ArraySaveNameEditField.Value + ".mat",'file')
                selection = uiconfirm(app.UIFigure,'Are you sure you want to overwrite?','Confirm Overwrite',...
                    'Icon','warning');
                if string(selection) == "Cancel"
                    warning("Antenna array not saved!");
                    return;
                end
            end
            
            app.ant.TxTable.Data = app.TxTable.Data;
            app.ant.RxTable.Data = app.RxTable.Data;
            savedant = app.ant;
            savePathFull = "./saved/" + app.ArraySaveNameEditField.Value + ".mat";
            save(savePathFull,"savedant");
        end

        % Button pushed function: ArrayLoadButton
        function ArrayLoadButtonPushed(app, event)
            if ~exist(app.ArrayLoadNameEditField.Value + ".mat",'file')
                uiconfirm(app.UIFigure,"No file called " + app.ArrayLoadNameEditField.Value + ".mat to load",'Cannot Load',...
                    "Options",{'OK'},'Icon','warning');
                warning("Antenna array not loaded!");
                return;
            end
            
            loadPathFull = "./saved/" + app.ArrayLoadNameEditField.Value + ".mat";
            load(loadPathFull,"savedant");
            
            app.TxTable.Data = savedant.TxTable.Data;
            app.RxTable.Data = savedant.RxTable.Data;
            
            app.ant = updateant(app);
        end

        % Value changed function: SARMethodDropDown
        function SARMethodDropDownValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: XStepSizeEditField
        function XStepSizeEditFieldValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: NumYStepsEditField
        function NumYStepsEditFieldValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: NumXStepsEditField
        function NumXStepsEditFieldValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: YStepSizeEditField
        function YStepSizeEditFieldValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: NumThetaStepsEditField
        function NumThetaStepsEditFieldValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: ThetaSizedegEditField
        function ThetaSizedegEditFieldValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: XStepSwitch
        function XStepSwitchValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: YStepSwitch
        function YStepSwitchValueChanged(app, event)
            app.sar = updatesar(app);
        end

        % Value changed function: TransmitterZmEditField
        function TransmitterZmEditFieldValueChanged(app, event)
            app.ReceiverZmEditField.Value = app.TransmitterZmEditField.Value;
        end

        % Button pushed function: SetupTargetButton
        function SetupTargetButtonPushed(app, event)
            app.target = updatetarget(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FMCWMIMOSARSimulatorLabel = uilabel(app.UIFigure);
            app.FMCWMIMOSARSimulatorLabel.Text = 'FMCW MIMO-SAR Simulator';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RadarSetupTab = uitab(app.TabGroup);
            app.RadarSetupTab.Title = 'Radar Setup';
            app.FMCWChirpParametersPanel = uipanel(app.RadarSetupTab);
            app.FMCWChirpParametersPanel.Title = 'FMCW Chirp Parameters';
            app.StartingFrequencyGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.StartingFrequencyGHzEditFieldLabel.Text = 'Starting Frequency (GHz)';
            app.StartingFrequencyGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.StartingFrequencyGHzEditField.Limits = [0 Inf];
            app.StartingFrequencyGHzEditField.ValueDisplayFormat = '%11.3f';
            app.StartingFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @StartingFrequencyGHzEditFieldValueChanged, true);
            app.StartingFrequencyGHzEditField.Value = 77;
            app.FreqSlopeMHzusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.FreqSlopeMHzusEditFieldLabel.Text = 'Freq Slope (MHz/us)';
            app.FreqSlopeMHzusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.FreqSlopeMHzusEditField.Limits = [0 Inf];
            app.FreqSlopeMHzusEditField.ValueDisplayFormat = '%11.3f';
            app.FreqSlopeMHzusEditField.ValueChangedFcn = createCallbackFcn(app, @FreqSlopeMHzusEditFieldValueChanged, true);
            app.FreqSlopeMHzusEditField.Value = 100.036;
            app.IdleTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.IdleTimeusEditFieldLabel.Text = 'Idle Time (us)';
            app.IdleTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.IdleTimeusEditField.Limits = [0 Inf];
            app.IdleTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.IdleTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @IdleTimeusEditFieldValueChanged, true);
            app.TXStartTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.TXStartTimeusEditFieldLabel.Text = 'TX Start Time (us)';
            app.TXStartTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.TXStartTimeusEditField.Limits = [0 Inf];
            app.TXStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.TXStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @TXStartTimeusEditFieldValueChanged, true);
            app.ADCStartTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.ADCStartTimeusEditFieldLabel.Text = 'ADC Start Time (us)';
            app.ADCStartTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.ADCStartTimeusEditField.Limits = [0 Inf];
            app.ADCStartTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.ADCStartTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @ADCStartTimeusEditFieldValueChanged, true);
            app.ADCSamplesEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.ADCSamplesEditFieldLabel.Text = 'ADC Samples';
            app.ADCSamplesEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.ADCSamplesEditField.Limits = [0 Inf];
            app.ADCSamplesEditField.RoundFractionalValues = 'on';
            app.ADCSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @ADCSamplesEditFieldValueChanged, true);
            app.ADCSamplesEditField.Value = 79;
            app.SamplingFrequencykspsEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.SamplingFrequencykspsEditFieldLabel.Text = 'Sampling Frequency (ksps)';
            app.SamplingFrequencykspsEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.SamplingFrequencykspsEditField.Limits = [2000 37500];
            app.SamplingFrequencykspsEditField.RoundFractionalValues = 'on';
            app.SamplingFrequencykspsEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingFrequencykspsEditFieldValueChanged, true);
            app.SamplingFrequencykspsEditField.Value = 2000;
            app.RampEndTimeusEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.RampEndTimeusEditFieldLabel.Text = 'Ramp End Time (us)';
            app.RampEndTimeusEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.RampEndTimeusEditField.Limits = [0 Inf];
            app.RampEndTimeusEditField.ValueDisplayFormat = '%11.3f';
            app.RampEndTimeusEditField.ValueChangedFcn = createCallbackFcn(app, @RampEndTimeusEditFieldValueChanged, true);
            app.RampEndTimeusEditField.Value = 39.98;
            app.CenterFrequencyGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.CenterFrequencyGHzEditFieldLabel.Text = 'Center Frequency (GHz)';
            app.CenterFrequencyGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.CenterFrequencyGHzEditField.Limits = [0 Inf];
            app.CenterFrequencyGHzEditField.ValueDisplayFormat = '%11.3f';
            app.CenterFrequencyGHzEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyGHzEditFieldValueChanged, true);
            app.CenterFrequencyGHzEditField.Value = 79;
            app.MaximumBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.MaximumBandwidthGHzEditFieldLabel.Text = 'Maximum Bandwidth (GHz)';
            app.MaximumBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.MaximumBandwidthGHzEditField.Limits = [0 Inf];
            app.MaximumBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumBandwidthGHzEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumBandwidthGHzEditFieldValueChanged, true);
            app.MaximumBandwidthGHzEditField.Value = 4;
            app.TotalBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.TotalBandwidthGHzEditFieldLabel.Text = 'Total Bandwidth (GHz)';
            app.TotalBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.TotalBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.TotalBandwidthGHzEditField.Editable = 'off';
            app.WavelengthmEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.WavelengthmEditFieldLabel.Text = 'Wavelength (m)';
            app.WavelengthmEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.WavelengthmEditField.ValueDisplayFormat = '%11.3f';
            app.WavelengthmEditField.Editable = 'off';
            app.MaximumRangemEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.MaximumRangemEditFieldLabel.Text = 'Maximum Range (m)';
            app.MaximumRangemEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.MaximumRangemEditField.ValueDisplayFormat = '%11.3f';
            app.MaximumRangemEditField.Editable = 'off';
            app.RangeResolutionmEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.RangeResolutionmEditFieldLabel.Text = 'Range Resolution (m)';
            app.RangeResolutionmEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.RangeResolutionmEditField.Editable = 'off';
            app.UsefulBandwidthGHzEditFieldLabel = uilabel(app.FMCWChirpParametersPanel);
            app.UsefulBandwidthGHzEditFieldLabel.Text = 'Useful Bandwidth (GHz)';
            app.UsefulBandwidthGHzEditField = uieditfield(app.FMCWChirpParametersPanel, 'numeric');
            app.UsefulBandwidthGHzEditField.ValueDisplayFormat = '%11.3f';
            app.UsefulBandwidthGHzEditField.Editable = 'off';
            app.TransmitterElementsPanel = uipanel(app.RadarSetupTab);
            app.TransmitterElementsPanel.Title = 'Transmitter Elements';
            app.TxTable = uitable(app.TransmitterElementsPanel);
            app.TxTable.ColumnName = {'x(λ)'; 'x(mm)'; 'y(λ)'; 'y(mm)'; 'On'};
            app.TxTable.RowName = {};
            app.TxTable.CellEditCallback = createCallbackFcn(app, @TxTableCellEdit, true);
            app.AddTxButton = uibutton(app.TransmitterElementsPanel, 'push');
            app.AddTxButton.ButtonPushedFcn = createCallbackFcn(app, @AddTxButtonPushed, true);
            app.AddTxButton.Text = 'Add Tx';
            app.RemoveTxButton = uibutton(app.TransmitterElementsPanel, 'push');
            app.RemoveTxButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveTxButtonPushed, true);
            app.RemoveTxButton.Text = 'Remove Tx';
            app.TransmitterZmEditFieldLabel = uilabel(app.TransmitterElementsPanel);
            app.TransmitterZmEditFieldLabel.Text = 'Transmitter Z (m)';
            app.TransmitterZmEditField = uieditfield(app.TransmitterElementsPanel, 'numeric');
            app.TransmitterZmEditField.Limits = [0 Inf];
            app.TransmitterZmEditField.ValueDisplayFormat = '%11.3f';
            app.TransmitterZmEditField.ValueChangedFcn = createCallbackFcn(app, @TransmitterZmEditFieldValueChanged, true);
            app.TransmitterZmEditField.Value = 0.25;
            app.ReceiverElementsPanel = uipanel(app.RadarSetupTab);
            app.ReceiverElementsPanel.Title = 'Receiver Elements';
            app.RxTable = uitable(app.ReceiverElementsPanel);
            app.RxTable.ColumnName = {'x(λ)'; 'x(mm)'; 'y(λ)'; 'y(mm)'; 'On'};
            app.RxTable.RowName = {};
            app.RxTable.CellEditCallback = createCallbackFcn(app, @RxTableCellEdit, true);
            app.AddRxButton = uibutton(app.ReceiverElementsPanel, 'push');
            app.AddRxButton.ButtonPushedFcn = createCallbackFcn(app, @AddRxButtonPushed, true);
            app.AddRxButton.Text = 'Add Rx';
            app.RemoveRxButton = uibutton(app.ReceiverElementsPanel, 'push');
            app.RemoveRxButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRxButtonPushed, true);
            app.RemoveRxButton.Text = 'Remove Rx';
            app.ReceiverZmEditFieldLabel = uilabel(app.ReceiverElementsPanel);
            app.ReceiverZmEditFieldLabel.Text = 'Receiver Z (m)';
            app.ReceiverZmEditField = uieditfield(app.ReceiverElementsPanel, 'numeric');
            app.ReceiverZmEditField.Limits = [0 Inf];
            app.ReceiverZmEditField.ValueDisplayFormat = '%11.3f';
            app.ReceiverZmEditField.Editable = 'off';
            app.ReceiverZmEditField.Value = 0.25;
            app.SARSetupPanel = uipanel(app.RadarSetupTab);
            app.SARSetupPanel.Title = 'SAR Setup';
            app.MethodDropDownLabel = uilabel(app.SARSetupPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.SARMethodDropDown = uidropdown(app.SARSetupPanel);
            app.SARMethodDropDown.Items = {'-', 'Linear', 'Rectilinear', 'Circular', 'Cylindrical'};
            app.SARMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @SARMethodDropDownValueChanged, true);
            app.SARMethodDropDown.Value = 'Linear';
            app.XStepSizeEditFieldLabel = uilabel(app.SARSetupPanel);
            app.XStepSizeEditFieldLabel.Text = 'X-Step Size';
            app.XStepSizeEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.XStepSizeEditField.Limits = [0 Inf];
            app.XStepSizeEditField.ValueDisplayFormat = '%11.3f';
            app.XStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @XStepSizeEditFieldValueChanged, true);
            app.XStepSizeEditField.Value = 0.25;
            app.NumYStepsEditFieldLabel = uilabel(app.SARSetupPanel);
            app.NumYStepsEditFieldLabel.Text = 'Num Y Steps';
            app.NumYStepsEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.NumYStepsEditField.Limits = [0 Inf];
            app.NumYStepsEditField.RoundFractionalValues = 'on';
            app.NumYStepsEditField.ValueDisplayFormat = '%11.0f';
            app.NumYStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumYStepsEditFieldValueChanged, true);
            app.NumYStepsEditField.Value = 25;
            app.NumXStepsEditFieldLabel = uilabel(app.SARSetupPanel);
            app.NumXStepsEditFieldLabel.Text = 'Num X Steps';
            app.NumXStepsEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.NumXStepsEditField.Limits = [0 Inf];
            app.NumXStepsEditField.RoundFractionalValues = 'on';
            app.NumXStepsEditField.ValueDisplayFormat = '%11.0f';
            app.NumXStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumXStepsEditFieldValueChanged, true);
            app.NumXStepsEditField.Value = 200;
            app.YStepSizeEditFieldLabel = uilabel(app.SARSetupPanel);
            app.YStepSizeEditFieldLabel.Text = 'Y-Step Size';
            app.YStepSizeEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.YStepSizeEditField.Limits = [0 Inf];
            app.YStepSizeEditField.ValueDisplayFormat = '%11.3f';
            app.YStepSizeEditField.ValueChangedFcn = createCallbackFcn(app, @YStepSizeEditFieldValueChanged, true);
            app.YStepSizeEditField.Value = 2;
            app.NumStepsEditFieldLabel = uilabel(app.SARSetupPanel);
            app.NumStepsEditFieldLabel.Text = 'Num θ Steps';
            app.NumThetaStepsEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.NumThetaStepsEditField.Limits = [0 Inf];
            app.NumThetaStepsEditField.RoundFractionalValues = 'on';
            app.NumThetaStepsEditField.ValueDisplayFormat = '%11.0f';
            app.NumThetaStepsEditField.ValueChangedFcn = createCallbackFcn(app, @NumThetaStepsEditFieldValueChanged, true);
            app.NumThetaStepsEditField.Value = 1024;
            app.SizedegEditFieldLabel = uilabel(app.SARSetupPanel);
            app.SizedegEditFieldLabel.Text = 'θ-Size (deg)';
            app.ThetaSizedegEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.ThetaSizedegEditField.Limits = [0 Inf];
            app.ThetaSizedegEditField.ValueDisplayFormat = '%11.3f';
            app.ThetaSizedegEditField.ValueChangedFcn = createCallbackFcn(app, @ThetaSizedegEditFieldValueChanged, true);
            app.ThetaSizedegEditField.Value = 360;
            app.XStepSwitch = uiswitch(app.SARSetupPanel, 'slider');
            app.XStepSwitch.Items = {'λ', 'mm'};
            app.XStepSwitch.ValueChangedFcn = createCallbackFcn(app, @XStepSwitchValueChanged, true);
            app.XStepSwitch.Value = 'λ';
            app.YStepSwitch = uiswitch(app.SARSetupPanel, 'slider');
            app.YStepSwitch.Items = {'λ', 'mm'};
            app.YStepSwitch.ValueChangedFcn = createCallbackFcn(app, @YStepSwitchValueChanged, true);
            app.YStepSwitch.Value = 'λ';
            app.YSizemEditFieldLabel = uilabel(app.SARSetupPanel);
            app.YSizemEditFieldLabel.Text = 'Y-Size (m)';
            app.YSizemEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.YSizemEditField.Limits = [0 Inf];
            app.YSizemEditField.ValueDisplayFormat = '%11.5f';
            app.YSizemEditField.Editable = 'off';
            app.YSizemEditField.Value = 0.25;
            app.XSizemEditFieldLabel = uilabel(app.SARSetupPanel);
            app.XSizemEditFieldLabel.Text = 'X-Size (m)';
            app.XSizemEditField = uieditfield(app.SARSetupPanel, 'numeric');
            app.XSizemEditField.Limits = [0 Inf];
            app.XSizemEditField.ValueDisplayFormat = '%11.5f';
            app.XSizemEditField.Editable = 'off';
            app.XSizemEditField.Value = 0.25;
            app.SaveLoadFMCWChripParametersPanel = uipanel(app.RadarSetupTab);
            app.SaveLoadFMCWChripParametersPanel.Title = 'Save/Load FMCW Chrip Parameters';
            app.FMCWSaveButton = uibutton(app.SaveLoadFMCWChripParametersPanel, 'push');
            app.FMCWSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FMCWSaveButtonPushed, true);
            app.FMCWSaveButton.Text = 'Save';
            app.FMCWLoadButton = uibutton(app.SaveLoadFMCWChripParametersPanel, 'push');
            app.FMCWLoadButton.ButtonPushedFcn = createCallbackFcn(app, @FMCWLoadButtonPushed, true);
            app.FMCWLoadButton.Text = 'Load';
            app.FMCWSaveNameEditField = uieditfield(app.SaveLoadFMCWChripParametersPanel, 'text');
            app.FMCWSaveNameEditField.Value = 'fmcw_v1';
            app.FMCWLoadNameEditField = uieditfield(app.SaveLoadFMCWChripParametersPanel, 'text');
            app.FMCWLoadNameEditField.Value = 'fmcw_v2';
            app.SaveLoadMIMOArrayPanel = uipanel(app.RadarSetupTab);
            app.SaveLoadMIMOArrayPanel.Title = 'Save/Load MIMO Array';
            app.ArraySaveButton = uibutton(app.SaveLoadMIMOArrayPanel, 'push');
            app.ArraySaveButton.ButtonPushedFcn = createCallbackFcn(app, @ArraySaveButtonPushed, true);
            app.ArraySaveButton.Text = 'Save';
            app.ArrayLoadButton = uibutton(app.SaveLoadMIMOArrayPanel, 'push');
            app.ArrayLoadButton.ButtonPushedFcn = createCallbackFcn(app, @ArrayLoadButtonPushed, true);
            app.ArrayLoadButton.Text = 'Load';
            app.ArraySaveNameEditField = uieditfield(app.SaveLoadMIMOArrayPanel, 'text');
            app.ArraySaveNameEditField.Value = 'AWR1243';
            app.ArrayLoadNameEditField = uieditfield(app.SaveLoadMIMOArrayPanel, 'text');
            app.ArrayLoadNameEditField.Value = 'AWR1243';
            app.AntAxes = uiaxes(app.RadarSetupTab);
            title(app.AntAxes, 'Title')
            xlabel(app.AntAxes, 'X')
            ylabel(app.AntAxes, 'Y')
            app.AntVirtualAxes = uiaxes(app.RadarSetupTab);
            title(app.AntVirtualAxes, 'Title')
            xlabel(app.AntVirtualAxes, 'X')
            ylabel(app.AntVirtualAxes, 'Y')
            app.SARAxes = uiaxes(app.RadarSetupTab);
            title(app.SARAxes, 'Title')
            xlabel(app.SARAxes, 'X')
            ylabel(app.SARAxes, 'Y')
            app.SARVirtualAxes = uiaxes(app.RadarSetupTab);
            title(app.SARVirtualAxes, 'Title')
            xlabel(app.SARVirtualAxes, 'X')
            ylabel(app.SARVirtualAxes, 'Y')
            app.TargetSetupTab = uitab(app.TabGroup);
            app.TargetSetupTab.Title = 'Target Setup';
            app.SetupTargetButton = uibutton(app.TargetSetupTab, 'push');
            app.SetupTargetButton.ButtonPushedFcn = createCallbackFcn(app, @SetupTargetButtonPushed, true);
            app.SetupTargetButton.Text = 'Setup Target';
            app.ReconstructImageTab = uitab(app.TabGroup);
            app.ReconstructImageTab.Title = 'Reconstruct Image';
            app.CreatedbyJosiahWSmithLabel = uilabel(app.UIFigure);
            app.CreatedbyJosiahWSmithLabel.Text = 'Created by: Josiah W. Smith';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = improved_sar_sim_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end