classdef TargetDataGeneration < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TargetDataGenerationUIFigure  matlab.ui.Figure
        ImportDataButton              matlab.ui.control.Button
        PointCloudPanel               matlab.ui.container.Panel
        ConvertPointCloudButton       matlab.ui.control.Button
        GridAverageSpinnerLabel       matlab.ui.control.Label
        GridAverageSpinner            matlab.ui.control.Spinner
        DownSampleButton              matlab.ui.control.Button
        ResetPointCloudButton         matlab.ui.control.Button
        NumberofVoxelsTextAreaLabel   matlab.ui.control.Label
        NumberofVoxelsTextArea        matlab.ui.control.TextArea
        ZMaxmmSliderLabel             matlab.ui.control.Label
        ZMaxmmSlider                  matlab.ui.control.Slider
        ExportToWorkspaceButton       matlab.ui.control.Button
        Label                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        voxelData; % Description
        ptCloudOriginal;
        ptCloud;
        appUIaxis;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            [file,path] = uigetfile('*.stl');
            app.TargetDataGenerationUIFigure.Visible = 'off';
            app.TargetDataGenerationUIFigure.Visible = 'on';
         
            fv = stlread([path file]);
            
            patch(fv,'FaceColor',       [0.8 0.8 1.0], ...
                'EdgeColor',       'none',        ...
                'FaceLighting',    'gouraud',     ...
                'AmbientStrength', 0.15);
            
            % Add a camera light, and tone down the specular highlighting
            camlight('headlight');
            material('dull');
            
            % Fix the axes scaling, and set a nice view angle
            axis('image');
            view([-135 35]);
            
            app.voxelData = fv.vertices;
            
            app.ConvertPointCloudButton.Enable = 'on';
            
        end

        % Button pushed function: ConvertPointCloudButton
        function ConvertPointCloudButtonPushed(app, event)
            yDataTemp = app.voxelData(:,3);
            
            app.voxelData(:,3) = app.voxelData(:,2);
            app.voxelData(:,2) = yDataTemp;
            
            app.ptCloudOriginal = pointCloud(app.voxelData);
            app.ptCloud = app.ptCloudOriginal;
            
            pcshow(app.ptCloud,'VerticalAxis','Y');
            view([0 -125]);
            xlabel('x');ylabel('y');zlabel('z');
            
            app.ZMaxmmSlider.Limits = [min(app.voxelData(:,3)),max(app.voxelData(:,3))];
            
            app.NumberofVoxelsTextArea.Value = num2str(app.ptCloud.Count);
            
            app.DownSampleButton.Enable = 'on';
            app.ResetPointCloudButton.Enable = 'on';
            app.ExportToWorkspaceButton.Enable = 'on';
        end

        % Button pushed function: DownSampleButton
        function DownSampleButtonPushed(app, event)
            app.ptCloud = pcdownsample(app.ptCloud,'gridAverage',app.GridAverageSpinner.Value);
            pcshow(app.ptCloud);
            view([0 -125]);
            xlabel('x');ylabel('y');zlabel('z');
            
            app.NumberofVoxelsTextArea.Value = num2str(app.ptCloud.Count);
            
            app.voxelData = app.ptCloud.Location;
        end

        % Button pushed function: ResetPointCloudButton
        function ResetPointCloudButtonPushed(app, event)
            app.ptCloud = app.ptCloudOriginal;
            pcshow(app.ptCloud);
            view([0 -125]);
            xlabel('x');ylabel('y');zlabel('z');
            app.NumberofVoxelsTextArea.Value = num2str(app.ptCloud.Count);
            
            app.voxelData = app.ptCloud.Location;
        end

        % Value changed function: ZMaxmmSlider
        function ZMaxmmSliderValueChanged(app, event)
            value = app.ZMaxmmSlider.Value;
            app.voxelData = app.voxelData(app.voxelData(:,3)<value,:);
            
            app.ptCloud = pointCloud(app.voxelData);
            pcshow(app.ptCloud);
            view([0 -125]);
            xlabel('x');ylabel('y');zlabel('z');
            
            app.NumberofVoxelsTextArea.Value = num2str(app.ptCloud.Count);
        end

        % Button pushed function: ExportToWorkspaceButton
        function ExportToWorkspaceButtonPushed(app, event)
            assignin('base','xyzMatrixT',app.voxelData);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TargetDataGenerationUIFigure = uifigure('Visible', 'off');
            app.TargetDataGenerationUIFigure.Name = 'TargetDataGenerationFigure';
            app.ImportDataButton = uibutton(app.TargetDataGenerationUIFigure, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.PointCloudPanel = uipanel(app.TargetDataGenerationUIFigure);
            app.PointCloudPanel.Title = 'Point Cloud';
            app.ConvertPointCloudButton = uibutton(app.PointCloudPanel, 'push');
            app.ConvertPointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertPointCloudButtonPushed, true);
            app.ConvertPointCloudButton.Enable = 'off';
            app.ConvertPointCloudButton.Text = 'Convert Point Cloud';
            app.GridAverageSpinnerLabel = uilabel(app.PointCloudPanel);
            app.GridAverageSpinnerLabel.Text = 'Grid Average';
            app.GridAverageSpinner = uispinner(app.PointCloudPanel);
            app.GridAverageSpinner.Step = 0.1;
            app.GridAverageSpinner.Value = 1;
            app.DownSampleButton = uibutton(app.PointCloudPanel, 'push');
            app.DownSampleButton.ButtonPushedFcn = createCallbackFcn(app, @DownSampleButtonPushed, true);
            app.DownSampleButton.Enable = 'off';
            app.DownSampleButton.Text = 'Down Sample';
            app.ResetPointCloudButton = uibutton(app.PointCloudPanel, 'push');
            app.ResetPointCloudButton.ButtonPushedFcn = createCallbackFcn(app, @ResetPointCloudButtonPushed, true);
            app.ResetPointCloudButton.Enable = 'off';
            app.ResetPointCloudButton.Text = 'Reset Point Cloud';
            app.NumberofVoxelsTextAreaLabel = uilabel(app.PointCloudPanel);
            app.NumberofVoxelsTextAreaLabel.Text = 'Number of Voxels';
            app.NumberofVoxelsTextArea = uitextarea(app.PointCloudPanel);
            app.ZMaxmmSliderLabel = uilabel(app.PointCloudPanel);
            app.ZMaxmmSliderLabel.Text = 'Z-Max (mm)';
            app.ZMaxmmSlider = uislider(app.PointCloudPanel);
            app.ZMaxmmSlider.ValueChangedFcn = createCallbackFcn(app, @ZMaxmmSliderValueChanged, true);
            app.ExportToWorkspaceButton = uibutton(app.PointCloudPanel, 'push');
            app.ExportToWorkspaceButton.ButtonPushedFcn = createCallbackFcn(app, @ExportToWorkspaceButtonPushed, true);
            app.ExportToWorkspaceButton.Enable = 'off';
            app.ExportToWorkspaceButton.Text = 'Export To Workspace';
            app.Label = uilabel(app.TargetDataGenerationUIFigure);
            app.Label.Text = {'Created by Muhammet Emin YANIK'; 'Advisor: Prof. Murat TORLAK'; 'The University of Texas at Dallas'};
            % Show the figure after all components are created
            app.TargetDataGenerationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TargetDataGeneration

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TargetDataGenerationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TargetDataGenerationUIFigure)
        end
    end
end